classdef appdtmf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RESETButton            matlab.ui.control.Button
        DECODEREditField       matlab.ui.control.EditField
        DECODEREditFieldLabel  matlab.ui.control.Label
        decodeButton           matlab.ui.control.Button
        Button_12              matlab.ui.control.Button
        Button_11              matlab.ui.control.Button
        Button_10              matlab.ui.control.Button
        Button_9               matlab.ui.control.Button
        Button_8               matlab.ui.control.Button
        Button_7               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pressedDigits = ''; % Initialize as empty string
    end
    
    
    methods (Access = private)
        
         function addPressedDigit(app, digit)
            % Check if the number of digits exceeds 10
    if numel(app.pressedDigits) >= 10
        % Display a popup indicating that the limit is reached
        uialert(app.UIFigure, 'Maximum 10 digits allowed.', 'Input Limit Exceeded', 'Icon', 'warning');
    else
        % Add the digit to the property
        app.pressedDigits = [app.pressedDigits, digit]; 
    end
         end

        % Method to plot and play DTMF signal
        function plotAndSoundDTMF(app, f1, f2)
            Fs = 8000; % Sampling frequency
            t = 0:0.000125:0.05;
            signal = sin(2*pi*f1*t) + sin(2*pi*f2*t); % DTMF signal

            plot(app.UIAxes, t, signal);
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'DTMF Signal Waveform');
            sound(signal, Fs);
        
            
        end
        
        
        % Reset the app to its initial state
function reset(app)
    % Clear the text edit field
    app.DECODEREditField.Value = '';


    %clear the pressed digits:
    app.pressedDigits = '';
    
    % Reset the UIAxes to its initial state
    cla(app.UIAxes); % Clear the axes
    title(app.UIAxes, 'Title');
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');
    zlabel(app.UIAxes, 'Z');
    
   
end

            
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
             app.addPressedDigit('1'); % Add pressed digit to the property
            app.plotAndSoundDTMF(697, 1209); % Plot and play DTMF signal
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
        
         app.addPressedDigit('2'); % Add pressed digit to the property
            app.plotAndSoundDTMF(697, 1336); % Plot and play DTMF signal
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
       
         app.addPressedDigit('3'); % Add pressed digit to the property
            app.plotAndSoundDTMF(697, 1477); % Plot and play DTMF signal
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
          
         app.addPressedDigit('7'); % Add pressed digit to the property
            app.plotAndSoundDTMF(852, 1209); % Plot and play DTMF signal
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
    
         app.addPressedDigit('4'); % Add pressed digit to the property
            app.plotAndSoundDTMF(770, 1209); % Plot and play DTMF signal
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
     
         app.addPressedDigit('5'); % Add pressed digit to the property
            app.plotAndSoundDTMF(770, 1336); % Plot and play DTMF signal
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
         
         app.addPressedDigit('6'); % Add pressed digit to the property
            app.plotAndSoundDTMF(770, 1477); % Plot and play DTMF signal
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
                
         app.addPressedDigit('8'); % Add pressed digit to the property
            app.plotAndSoundDTMF(852, 1336); % Plot and play DTMF signal
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
                 
         app.addPressedDigit('9'); % Add pressed digit to the property
            app.plotAndSoundDTMF(852, 1477); % Plot and play DTMF signal
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            
         app.addPressedDigit('*'); % Add pressed digit to the property
            app.plotAndSoundDTMF(941, 1209); % Plot and play DTMF signal
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
             
         app.addPressedDigit('0'); % Add pressed digit to the property
            app.plotAndSoundDTMF(941, 1336); % Plot and play DTMF signal
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
                
         app.addPressedDigit('#'); % Add pressed digit to the property
            app.plotAndSoundDTMF(941, 1477); % Plot and play DTMF signal
        end

        % Button pushed function: decodeButton
        function decodeButtonPushed(app, event)
    
      
 app.DECODEREditField.Value = app.pressedDigits;
 
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            reset(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '*';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '0';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '#';
            app.decodeButton = uibutton(app.UIFigure, 'push');
            app.decodeButton.ButtonPushedFcn = createCallbackFcn(app, @decodeButtonPushed, true);
            app.decodeButton.Text = 'decode';
            app.DECODEREditFieldLabel = uilabel(app.UIFigure);
            app.DECODEREditFieldLabel.Text = 'DECODER';
            app.DECODEREditField = uieditfield(app.UIFigure, 'text');
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appdtmf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end