classdef dtmf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        STARTButton            matlab.ui.control.Button
        decoderEditField       matlab.ui.control.EditField
        decoderEditFieldLabel  matlab.ui.control.Label
        callerButton           matlab.ui.control.Button
        Button_12              matlab.ui.control.Button
        Button_11              matlab.ui.control.Button
        Button_10              matlab.ui.control.Button
        Button_9               matlab.ui.control.Button
        Button_8               matlab.ui.control.Button
        Button_7               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end


  methods (Access = private)
        
       function buttonPushed(app, event)

           
            % Get the source object (the button that was pressed)
            source = event.Source;
            % Extract the button text (digit)
            pressedButton = source.Text;
            
            % Update decoder text field
            app.decoderEditField.Value = pressedButton;
            
            % Generate DTMF tone based on pressed button
            switch pressedButton
                case '1'
                    f1 = 697;
                    f2 = 1209;
                case '2'
                    f1 = 697;
                    f2 = 1336;
                case '3'
                    f1 = 697;
                    f2 = 1663;
                case '4'
                    f1 = 770;
                    f2 = 1209;
                case '5'
                    f1 = 770;
                    f2 = 1336;
                case '6'
                    f1 = 770;
                    f2 = 1477;
                case '7'
                    f1 = 852;
                    f2 = 1209;
                case '8'
                    f1 = 852;
                    f2 = 1447;
                case '9'
                    f1 = 852;
                    f2 = 1336;
                case '*'
                    f1 = 941;
                    f2 = 1209;
                case '0'
                    f1 = 941;
                    f2 = 1336;
                case '#'
                    f1 = 941;
                    f2 = 1477;
                otherwise
                    % Handle invalid input (optional)
                    return;
            end
            
            % Generate and plot DTMF tone
            t = 0:0.000125:0.05;
            fs = 8000;
            y1 = 0.25*sin(2*pi*f1*t);
            y2 = 0.25*sin(2*pi*f2*t);
            y = y1 + y2;
            
            sound(y, fs);
            plot(app.UIAxes, t, y);
            title(app.UIAxes, 'DTMF Input Time Domain Signal');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
             app.Button.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
        end

        % Button pushed function: callerButton
        function callerButtonPushed(app, event)
           
            newApp = appdtmf();
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Text = '*';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Text = '0';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.Text = '#';
            app.callerButton = uibutton(app.UIFigure, 'push');
            app.callerButton.ButtonPushedFcn = createCallbackFcn(app, @callerButtonPushed, true);
            app.callerButton.Text = 'caller';
            app.decoderEditFieldLabel = uilabel(app.UIFigure);
            app.decoderEditFieldLabel.Text = 'decoder';
            app.decoderEditField = uieditfield(app.UIFigure, 'text');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dtmf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end